classdef chromaSubsampling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        KevinCruseandRachelHammerLabel  matlab.ui.control.Label
        SubsamplingButton               matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image string % Property to store image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Prompt user to select an image file
            fileName = uigetfile('*.png');
            if isequal(fileName,0)
                return;
            end
            app.Image = fileName;

            % Display the image in the first app.UIAxes
            imshow(app.Image, 'Parent', app.UIAxes);
        end

        % Button pushed function: SubsamplingButton
        function SubsamplingButtonPushed(app, event)
            % Call ChromaSubsampling function to generate output images
            ChromaSubsampling(app.Image);

            % 444
            %imshow(imread(fullfile(pwd, 'output_444.png')), 'Parent', app.UIAxes);
            imshow(imread(fullfile(pwd, 'output_Cb_444.png')), 'Parent', app.UIAxes4);
            imshow(imread(fullfile(pwd, 'output_Cr_444.png')), 'Parent', app.UIAxes7);

            % 422
            imshow(imread(fullfile(pwd, 'output_422.png')), 'Parent', app.UIAxes2);
            imshow(imread(fullfile(pwd, 'output_Cb_422.png')), 'Parent', app.UIAxes5);
            imshow(imread(fullfile(pwd, 'output_Cr_422.png')), 'Parent', app.UIAxes8);

            % 411
            imshow(imread(fullfile(pwd, 'output_411.png')), 'Parent', app.UIAxes3);
            imshow(imread(fullfile(pwd, 'output_Cb_411.png')), 'Parent', app.UIAxes6);
            imshow(imread(fullfile(pwd, 'output_Cr_411.png')), 'Parent', app.UIAxes9);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '4:4:4 (Original)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '4:2:2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '4:1:1')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '4:4:4 (Cb)')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, '4:2:2 (Cb)')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, '4:1:1 (Cb)')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, '4:4:4 (Cr)')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, '4:2:2 (Cr)')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, '4:1:1 (Cr)')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SubsamplingButton = uibutton(app.UIFigure, 'push');
            app.SubsamplingButton.ButtonPushedFcn = createCallbackFcn(app, @SubsamplingButtonPushed, true);
            app.SubsamplingButton.Text = 'Subsampling';
            app.KevinCruseandRachelHammerLabel = uilabel(app.UIFigure);
            app.KevinCruseandRachelHammerLabel.Text = 'Kevin Cruse and Rachel Hammer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chromaSubsampling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end